classdef communicate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        soundButton           matlab.ui.control.Button
        endRecordingButton    matlab.ui.control.Button
        sendButton            matlab.ui.control.Button
        sendTextArea          matlab.ui.control.TextArea
        startRecordingButton  matlab.ui.control.Button
        receiveTextArea       matlab.ui.control.TextArea
        loadStringButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        myRecorder audiorecorder; % 录音
        t timer; % 录音计时器
        isRecording = false;
        fs = 48000;
        duration = 0.025;                           % 指定生成的信号持续时间
        f = 6000;                                   % 指定声音信号频率
        chirp_f1 = 15000;                           % start freq
        chirp_f2 = 18000;                           % end freq
        sendText = '';
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: sendButton
        function sendButtonPushed(app, event)
            app.sendText = char(app.sendTextArea.Value);
            % 编码
            signal = encode(app.sendText, app.fs, app.duration, app.f, app.chirp_f1, app.chirp_f2);
            % 生成音频文件
            audiowrite('sender.wav', signal, app.fs);
            % 波形图
            figure(1);
            plot(signal);
            % 播放
            % sound(signal, app.Fs);
        end

        % Button pushed function: startRecordingButton
        function startRecordingButtonPushed(app, event)
            if app.isRecording == true
                return
            end
           
            app.receiveTextArea.Value = 'start recording...';

            % start recording
            app.isRecording = true;
            app.myRecorder = audiorecorder(app.fs, 16 , 1);
            record(app.myRecorder);
        end

        % Button pushed function: endRecordingButton
        function endRecordingButtonPushed(app, event)
            if app.isRecording == false
                return
            end
            
            % end recording
            app.isRecording = false;
            app.receiveTextArea.Value = 'end recording...';
            pause(app.myRecorder);
            signal = getaudiodata(app.myRecorder);
            % 波形图
            figure(20);
            plot(signal)
            
            % 写入音频文件
            audiowrite('receiver.wav', signal, app.fs);
            
            % 解码
            signal = signal';
            receiveText = decode (signal, app.fs, app.duration, app.f, app.chirp_f1, app.chirp_f2);
            app.receiveTextArea.Value = receiveText;
            % 结果保存文件
            save receiver.txt -ascii receiveText
        end

        % Button pushed function: loadStringButton
        function loadButtonPushed(app, event)

            origin_str = importdata('load.txt');
            origin_str = char(origin_str);
            app.sendTextArea.Value = origin_str;
            app.sendText = origin_str;
        end

        % Button pushed function: soundButton
        function soundButtonPushed(app, event)
            [signal, ~] = audioread('sender.wav');
            sound(signal);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.loadStringButton = uibutton(app.UIFigure, 'push');
            app.loadStringButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.loadStringButton.Text = 'Load';
            app.receiveTextArea = uitextarea(app.UIFigure);
            app.startRecordingButton = uibutton(app.UIFigure, 'push');
            app.startRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @startRecordingButtonPushed, true);
            app.startRecordingButton.Text = 'StartRecording';
            app.sendTextArea = uitextarea(app.UIFigure);
            app.sendButton = uibutton(app.UIFigure, 'push');
            app.sendButton.ButtonPushedFcn = createCallbackFcn(app, @sendButtonPushed, true);
            app.sendButton.Text = 'Send';
            app.endRecordingButton = uibutton(app.UIFigure, 'push');
            app.endRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @endRecordingButtonPushed, true);
            app.endRecordingButton.Text = 'EndRecording';
            app.soundButton = uibutton(app.UIFigure, 'push');
            app.soundButton.ButtonPushedFcn = createCallbackFcn(app, @soundButtonPushed, true);
            app.soundButton.Text = 'Sound';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = communicate

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end