classdef location < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        labelNode1              matlab.ui.control.Label
        nodex1                  matlab.ui.control.NumericEditField
        nodey1                  matlab.ui.control.NumericEditField
        labelNode2              matlab.ui.control.Label
        nodex2                  matlab.ui.control.NumericEditField
        nodey2                  matlab.ui.control.NumericEditField
        labelNode3              matlab.ui.control.Label
        nodex3                  matlab.ui.control.NumericEditField
        nodey3                  matlab.ui.control.NumericEditField
        measureDistanceButton1  matlab.ui.control.Button
        measureDistanceButton2  matlab.ui.control.Button
        measureDistanceButton3  matlab.ui.control.Button
        labelResult             matlab.ui.control.Label
        resultx                 matlab.ui.control.NumericEditField
        resulty                 matlab.ui.control.NumericEditField
        labelDistance1          matlab.ui.control.Label
        labelDistance2          matlab.ui.control.Label
        labelDistance3          matlab.ui.control.Label
        realcoorx               matlab.ui.control.NumericEditField
        realcoory               matlab.ui.control.NumericEditField
        labelResult_2           matlab.ui.control.Label
        labelTimeCost           matlab.ui.control.Label
        labelErrorDistance      matlab.ui.control.Label
        locateButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        durings = [];            % during time array
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: measureDistanceButton1
        function measureDistanceButton1Pushed(app, event)
            [distance, dur] = AServer();
            %distance = 1.155;
            %dur = 0.1;
            app.labelDistance1.Text = num2str(distance, 4);
            app.durings = [app.durings, dur];
        end

        % Button pushed function: measureDistanceButton2
        function measureDistanceButton2Pushed(app, event)
            [distance, dur] = AServer();
            %distance = 1.155;
            %dur = 0.1;
            app.labelDistance2.Text = num2str(distance, 4);
            app.durings = [app.durings, dur];
        end

        % Button pushed function: measureDistanceButton3
        function measureDistanceButton3Pushed(app, event)
            [distance, dur] = AServer();
            %distance = 1.155;
            %dur = 0.1;
            app.labelDistance3.Text = num2str(distance, 4);
            app.durings = [app.durings, dur];
        end

        % Button pushed function: locateButton
        function locateButtonPushed(app, event)
            % result coordinates
            tic;
            nodes = [
                app.nodex1.Value, app.nodey1.Value; 
                app.nodex2.Value, app.nodey2.Value; 
                app.nodex3.Value, app.nodey3.Value];
            distances = [str2double(app.labelDistance1.Text),str2double(app.labelDistance2.Text),str2double(app.labelDistance3.Text)];
            result = Locate(nodes, distances);
            app.durings = [app.durings, toc];
            app.resultx.Value = result(1);
            app.resulty.Value = result(2);
            
            % running time
            app.labelTimeCost.Text = "TimeCost: " + num2str(sum(app.durings), 6) + " s";
            app.durings = [];
            
            % error distance
            realCoor = [app.realcoorx.Value; app.realcoory.Value];
            errorDistance = sqrt(sum((realCoor - result).*(realCoor - result)));
            app.labelErrorDistance.Text = "ErrorDistance: " + num2str(errorDistance) + " m";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.labelNode1 = uilabel(app.UIFigure);
            app.labelNode1.Text = 'Node1';
            app.nodex1 = uieditfield(app.UIFigure, 'numeric');
            app.nodey1 = uieditfield(app.UIFigure, 'numeric');
            app.labelNode2 = uilabel(app.UIFigure);
            app.labelNode2.Text = 'Node2';
            app.nodex2 = uieditfield(app.UIFigure, 'numeric');
            app.nodey2 = uieditfield(app.UIFigure, 'numeric');
            app.labelNode3 = uilabel(app.UIFigure);
            app.labelNode3.Text = 'Node3';
            app.nodex3 = uieditfield(app.UIFigure, 'numeric');
            app.nodey3 = uieditfield(app.UIFigure, 'numeric');
            app.measureDistanceButton1 = uibutton(app.UIFigure, 'push');
            app.measureDistanceButton1.ButtonPushedFcn = createCallbackFcn(app, @measureDistanceButton1Pushed, true);
            app.measureDistanceButton1.Text = 'MeasureDistance:';
            app.measureDistanceButton2 = uibutton(app.UIFigure, 'push');
            app.measureDistanceButton2.ButtonPushedFcn = createCallbackFcn(app, @measureDistanceButton2Pushed, true);
            app.measureDistanceButton2.Text = 'MeasureDistance:';
            app.measureDistanceButton3 = uibutton(app.UIFigure, 'push');
            app.measureDistanceButton3.ButtonPushedFcn = createCallbackFcn(app, @measureDistanceButton3Pushed, true);
            app.measureDistanceButton3.Text = 'MeasureDistance:';
            app.labelResult = uilabel(app.UIFigure);
            app.labelResult.Text = 'Result';
            app.resultx = uieditfield(app.UIFigure, 'numeric');
            app.resulty = uieditfield(app.UIFigure, 'numeric');
            app.labelDistance1 = uilabel(app.UIFigure);
            app.labelDistance1.Text = 'distance1';
            app.labelDistance2 = uilabel(app.UIFigure);
            app.labelDistance2.Text = 'distance1';
            app.labelDistance3 = uilabel(app.UIFigure);
            app.labelDistance3.Text = 'distance1';
            app.realcoorx = uieditfield(app.UIFigure, 'numeric');
            app.realcoory = uieditfield(app.UIFigure, 'numeric');
            app.labelResult_2 = uilabel(app.UIFigure);
            app.labelResult_2.Text = 'RealCoor';
            app.labelTimeCost = uilabel(app.UIFigure);
            app.labelTimeCost.Text = 'TimeCost: ';
            app.labelErrorDistance = uilabel(app.UIFigure);
            app.labelErrorDistance.Text = 'ErrorDistance:';
            app.locateButton = uibutton(app.UIFigure, 'push');
            app.locateButton.ButtonPushedFcn = createCallbackFcn(app, @locateButtonPushed, true);
            app.locateButton.Text = 'Locate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = location

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end